classdef settingsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        ColorPicker           matlab.ui.control.ColorPicker
        ColorPickerLabel      matlab.ui.control.Label
        ButtonGroup2          matlab.ui.container.ButtonGroup
        Button3_2             matlab.ui.control.ToggleButton
        Button2_2             matlab.ui.control.ToggleButton
        Button_2              matlab.ui.control.ToggleButton
        ListBox               matlab.ui.control.ListBox
        ListBoxLabel          matlab.ui.control.Label
        ButtonGroup           matlab.ui.container.ButtonGroup
        Button3               matlab.ui.control.RadioButton
        Button2               matlab.ui.control.RadioButton
        Button                matlab.ui.control.RadioButton
        DropDown              matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        exitButton            matlab.ui.control.Button
        playPauseMusicButton  matlab.ui.control.StateButton
    end

    properties (Access = private)
        MainApp % Description
    end
   %% SOUNDS 
    methods (Access = public)
        
        function [] = Click(app)
            [y, Fs] = audioread("CLICK.mp3")
            player = audioplayer(3.5*y, Fs)
            play(player)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, background, music)
            % app.playPauseMusicButton.Value = music;
            % % = background
            % app.MainApp = mainapp;
        end

        % Value changed function: playPauseMusicButton
        function playPauseMusicButtonValueChanged(app, event)
            value = app.playPauseMusicButton.Value;
            if value == 1
                app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'pauseSoundButton.png');
                % play(blahblahblah(app)) for sound
            else
                app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'playSoundButton.png');
               % to end sound pause(blahblahblah(app))
            end
        end

        % Button pushed function: exitButton
        function exitButtonPushed(app, event)
            app.MainApp.settingsButton.Enable = 'on';
            delete(app);

            Click(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % if isvalid(app.MainApp)
            % app.MainApp.settingsButton.Enable = 'on';
            % end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.playPauseMusicButton = uibutton(app.UIFigure, 'state');
            app.playPauseMusicButton.ValueChangedFcn = createCallbackFcn(app, @playPauseMusicButtonValueChanged, true);
            app.playPauseMusicButton.Text = '';
            app.playPauseMusicButton.Value = true;
            app.playPauseMusicButton.Icon = fullfile(pathToMLAPP, 'playSoundButton.png');
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.ButtonPushedFcn = createCallbackFcn(app, @exitButtonPushed, true);
            app.exitButton.Icon = fullfile(pathToMLAPP, 'images', 'buttons', 'okButton.png');
            app.exitButton.Text = '';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.ListBoxLabel = uilabel(app.UIFigure);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.UIFigure);
            app.ButtonGroup2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup2.Title = 'Button Group2';
            app.Button_2 = uitogglebutton(app.ButtonGroup2);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'images', 'cards', '2 DIAMOND.svg');
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2_2 = uitogglebutton(app.ButtonGroup2);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uitogglebutton(app.ButtonGroup2);
            app.Button3_2.Text = 'Button3';
            app.ColorPickerLabel = uilabel(app.UIFigure);
            app.ColorPickerLabel.Text = 'Color Picker';
            app.ColorPicker = uicolorpicker(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = settingsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end